classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportAudioFileButton         matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        PauseButton                   matlab.ui.control.Button
        InputOptionsDropDownLabel     matlab.ui.control.Label
        InputOptionsDropDown          matlab.ui.control.DropDown
        InputLabel                    matlab.ui.control.Label
        OutputLabel                   matlab.ui.control.Label
        OutputPlayButton              matlab.ui.control.Button
        OutputPauseButton             matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        AmplitudeEditFieldLabel       matlab.ui.control.Label
        AmplitudeEditField            matlab.ui.control.NumericEditField
        PhaseEditFieldLabel           matlab.ui.control.Label
        PhaseEditField                matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        FrequencyEditField            matlab.ui.control.NumericEditField
        SampleFreq1000EditFieldLabel  matlab.ui.control.Label
        SampleFreq1000EditField       matlab.ui.control.NumericEditField
        DurationsEditFieldLabel       matlab.ui.control.Label
        DurationsEditField            matlab.ui.control.NumericEditField
        GenerateButton                matlab.ui.control.Button
        Image_2                       matlab.ui.control.Image
        AttackEditFieldLabel          matlab.ui.control.Label
        AttackEditField               matlab.ui.control.NumericEditField
        SustainEditFieldLabel         matlab.ui.control.Label
        SustainEditField              matlab.ui.control.NumericEditField
        WidgetDropDownLabel           matlab.ui.control.Label
        WidgetDropDown                matlab.ui.control.DropDown
        Button                        matlab.ui.control.Button
        ProcessButton                 matlab.ui.control.Button
        AttackLenEditFieldLabel       matlab.ui.control.Label
        AttackLenEditField            matlab.ui.control.NumericEditField
        DecayLenEditFieldLabel        matlab.ui.control.Label
        DecayLenEditField             matlab.ui.control.NumericEditField
        SustainLenEditFieldLabel      matlab.ui.control.Label
        SustainLenEditField           matlab.ui.control.NumericEditField
        RecLenEditFieldLabel          matlab.ui.control.Label
        RecLenEditField               matlab.ui.control.NumericEditField
        DemoSongsDropDownLabel        matlab.ui.control.Label
        DemoSongsDropDown             matlab.ui.control.DropDown
        thenclickaddEditFieldLabel    matlab.ui.control.Label
        thenclickaddEditField         matlab.ui.control.NumericEditField
        AddButton                     matlab.ui.control.Button
        ChiaroClearButton             matlab.ui.control.Button
        TypeforManualSongButtonGroup  matlab.ui.container.ButtonGroup
        NormalButton                  matlab.ui.control.RadioButton
        ADSRButton                    matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
            global y;
            global fs;
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'; '*.*'}, 'Pick an audio file');
            if ~ischar(filename); return; end
            try
                fullname = fullfile(filepath, filename);
                [y, fs] = audioread(fullname);
                y(:,1) = [];
            catch ME
                uiwait(msgbox('Could not open that file with audioread'));
            end
            plot(app.UIAxes2,y)
            data_fft = fft(y);
            plot(app.UIAxes,abs(data_fft(:,1)));
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           global Player;
           global y;
           global fs;
            try
                Player = audioplayer(y,fs);
                play(Player);
            catch ME
                uiwait(msgbox('Could not play the input!'));
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global Player;
            stop(Player);
        end

        % Value changed function: InputOptionsDropDown
        function InputOptionsDropDownValueChanged(app, event)
            value = app.InputOptionsDropDown.Value;
            global inputValue;
            app.ChiaroClearButton.Visible = 'off';
            app.ChiaroClearButton.Enable = 'off';
            app.AddButton.Enable = 'off';
            app.AddButton.Visible = 'off';
            app.DemoSongsDropDown.Visible = 'off';
            app.DemoSongsDropDown.Enable = 'off';
            app.AddButton.Enable = 'off';
            app.AddButton.Visible = 'off';
            app.thenclickaddEditField.Enable = 'off';
            app.thenclickaddEditField.Visible = 'off';
            app.ImportAudioFileButton.Visible = 'off';
            app.GenerateButton.Visible = 'off';
            app.DurationsEditField.Visible = 'off';
            app.SampleFreq1000EditField.Visible = 'off';
            app.FrequencyEditField.Visible = 'off';
            app.PhaseEditField.Visible = 'off';
            app.AmplitudeEditField.Visible = 'off';
            app.DurationsEditFieldLabel.Visible = 'off';
            app.SampleFreq1000EditFieldLabel.Visible = 'off';
            app.FrequencyEditFieldLabel.Visible = 'off';
            app.PhaseEditFieldLabel.Visible = 'off';
            app.AmplitudeEditFieldLabel.Visible = 'off';
            app.DemoSongsDropDownLabel.Visible = 'off';
            app.thenclickaddEditFieldLabel.Visible = 'off';
            app.TypeforManualSongButtonGroup.Visible = 'off';
            app.TypeforManualSongButtonGroup.Enable = 'off';
            switch value
                case 'Manual Mode'
                    app.AddButton.Enable = 'on';
                    app.AddButton.Visible = 'on';
                    app.thenclickaddEditField.Enable = 'on';
                    app.thenclickaddEditField.Visible = 'on';
                    app.thenclickaddEditFieldLabel.Visible = 'on';
                    app.ChiaroClearButton.Visible = 'on';
                    app.ChiaroClearButton.Enable = 'on';
                    app.TypeforManualSongButtonGroup.Visible = 'on';
                    app.TypeforManualSongButtonGroup.Enable = 'on';
                    inputValue = 2;
                case 'Demo Song'
                    app.DemoSongsDropDown.Visible = 'on';
                    app.DemoSongsDropDown.Enable = 'on';
                    app.DemoSongsDropDownLabel.Visible = 'on';
                    app.TypeforManualSongButtonGroup.Visible = 'on';
                    app.TypeforManualSongButtonGroup.Enable = 'on';
                    inputValue = 1;
                case 'Import File'
                    app.ImportAudioFileButton.Visible = 'on';
                case 'Sinusoidal'
                    app.GenerateButton.Visible = 'on';
                    app.DurationsEditField.Visible = 'on';
                    app.SampleFreq1000EditField.Visible = 'on';
                    app.FrequencyEditField.Visible = 'on';
                    app.PhaseEditField.Visible = 'on';
                    app.AmplitudeEditField.Visible = 'on';
                    app.DurationsEditFieldLabel.Visible = 'on';
                    app.SampleFreq1000EditFieldLabel.Visible = 'on';
                    app.FrequencyEditFieldLabel.Visible = 'on';
                    app.PhaseEditFieldLabel.Visible = 'on';
                    app.AmplitudeEditFieldLabel.Visible = 'on';
                    inputValue = 4;
                case 'Pulse'
                    app.GenerateButton.Visible = 'on';
                    app.DurationsEditField.Visible = 'on';
                    app.SampleFreq1000EditField.Visible = 'on';
                    app.FrequencyEditField.Visible = 'on';
                    app.PhaseEditField.Visible = 'on';
                    app.AmplitudeEditField.Visible = 'on';
                    app.DurationsEditFieldLabel.Visible = 'on';
                    app.SampleFreq1000EditFieldLabel.Visible = 'on';
                    app.FrequencyEditFieldLabel.Visible = 'on';
                    app.PhaseEditFieldLabel.Visible = 'on';
                    app.AmplitudeEditFieldLabel.Visible = 'on';
                    inputValue = 5;
                case 'Sawtooth'
                    app.GenerateButton.Visible = 'on';
                    app.DurationsEditField.Visible = 'on';
                    app.SampleFreq1000EditField.Visible = 'on';
                    app.FrequencyEditField.Visible = 'on';
                    app.PhaseEditField.Visible = 'on';
                    app.AmplitudeEditField.Visible = 'on';
                    app.DurationsEditFieldLabel.Visible = 'on';
                    app.SampleFreq1000EditFieldLabel.Visible = 'on';
                    app.FrequencyEditFieldLabel.Visible = 'on';
                    app.PhaseEditFieldLabel.Visible = 'on';
                    app.AmplitudeEditFieldLabel.Visible = 'on';
                    inputValue = 3;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            global inputValue;
            global y;
            global fs;
            if inputValue == 4
                y = sine_generator(app.AmplitudeEditField.Value,app.PhaseEditField.Value,app.FrequencyEditField.Value,app.SampleFreq1000EditField.Value,app.DurationsEditField.Value);
                fs = app.SampleFreq1000EditField.Value;
                y = y';
            elseif inputValue == 5
                y = pulse_generator(app.AmplitudeEditField.Value,app.PhaseEditField.Value,app.FrequencyEditField.Value,app.SampleFreq1000EditField.Value,app.DurationsEditField.Value);
                fs = app.SampleFreq1000EditField.Value;
                y = y';
            elseif inputValue == 3
                y = sawtooth_generator(app.AmplitudeEditField.Value,app.PhaseEditField.Value,app.FrequencyEditField.Value,app.SampleFreq1000EditField.Value,app.DurationsEditField.Value);
                fs = app.SampleFreq1000EditField.Value;
                y = y';
            end
            plot(app.UIAxes2,y)
            plot(app.UIAxes,abs(fft(y)))
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            global y;
            global fs;
            global output;
            switch app.WidgetDropDown.Value
                case 'ADSR Envelope'
                    total = app.RecLenEditField.Value + app.SustainLenEditField.Value + app.DecayLenEditField.Value + app.AttackLenEditField.Value;
                    length = [(app.AttackLenEditField.Value / total);(app.DecayLenEditField.Value / total);(app.DecayLenEditField.Value / total)];
                    adsr = adsr_gen(app.AttackEditField.Value,app.SustainEditField.Value,length,fs,(size(y,1) / fs));
                    size(y)
                    size(adsr)
                    size(y,1)/fs
                    output = adsr(:) .* y(:);
                    %output = adsr_piano(y,fs);
            end
            plot(app.UIAxes4,output);
            plot(app.UIAxes3,abs(fft(output)));
        end

        % Button pushed function: OutputPlayButton
        function OutputPlayButtonPushed(app, event)
            global output;
            global fs;
            global outputPlayer;
            global demosong;
            global pianotype;
            global inputValue;
            global manualInput;
            if app.NormalButton.Value == 1
                pianotype = 1;
            elseif app.ADSRButton.Value == 1
                pianotype = 2;
            end
            if inputValue == 1
                play_sequence(inputValue,demosong,pianotype)
                plot(app.UIAxes4, output_sequence(inputValue,demosong,pianotype));
                plot(app.UIAxes3, abs(fft(output_sequence(inputValue,demosong,pianotype))));
            elseif inputValue == 2
                play_sequence(inputValue,manualInput,pianotype)
                plot(app.UIAxes4, output_sequence(inputValue,demosong,pianotype));
                plot(app.UIAxes3, abs(fft(output_sequence(inputValue,demosong,pianotype))));
            else
                outputPlayer = audioplayer(output,fs);
                play(outputPlayer);                
            end
        end

        % Button pushed function: OutputPauseButton
        function OutputPauseButtonPushed(app, event)
            global outputPlayer;
            stop(outputPlayer);
        end

        % Callback function
        function Slider2ValueChanging(app, event)
            changingValue = event.Value;
            app.AttackDurationSlider.Value = 100-app.DecayDurationSlider.Value-app.SustainDurationSlider.Value;
        end

        % Callback function
        function AttackDurationSliderValueChanged(app, event)
%             value = app.AttackDurationSlider.Value;
%             app.SustainDurationSlider.Value = 100-app.DecayDurationSlider.Value-app.AttackDurationSlider.Value;
        end

        % Callback function
        function SustainDurationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.DecayDurationSlider.Value = 100-app.SustainDurationSlider.Value-app.AttackDurationSlider.Value;
            
        end

        % Callback function
        function AttackDurationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SustainDurationSlider.Value = 100-app.DecayDurationSlider.Value-app.AttackDurationSlider.Value;

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            global manualInput;
            if (9 > app.thenclickaddEditField.Value) && (app.thenclickaddEditField.Value > 0) 
                manualInput(1,size(manualInput,2)+1) = app.thenclickaddEditField.Value
                app.thenclickaddEditField.Value = 0;
            else
                app.thenclickaddEditField.Value = -1;
            end
            
        end

        % Button pushed function: ChiaroClearButton
        function ChiaroClearButtonPushed(app, event)
            global manualInput;
            manualInput = [];
        end

        % Value changed function: DemoSongsDropDown
        function DemoSongsDropDownValueChanged(app, event)
            value = app.DemoSongsDropDown.Value;
            global demosong;
            demosong = 1;
            switch value
                case 'Happy Birthday'
                    demosong = 1;
                case 'London Bridge'
                    demosong = 2;
                case 'Anime'
                    demosong = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Visible = 'off';
            app.ImportAudioFileButton.Text = {'Import Audio File'; ''};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Stop';
            app.InputOptionsDropDownLabel = uilabel(app.UIFigure);
            app.InputOptionsDropDownLabel.Text = 'Input Options';
            app.InputOptionsDropDown = uidropdown(app.UIFigure);
            app.InputOptionsDropDown.Items = {' ', 'Demo Song', 'Manual Mode', 'Sinusoidal', 'Sawtooth', 'Import File', 'Pulse'};
            app.InputOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @InputOptionsDropDownValueChanged, true);
            app.InputOptionsDropDown.Value = ' ';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            app.OutputPlayButton = uibutton(app.UIFigure, 'push');
            app.OutputPlayButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPlayButtonPushed, true);
            app.OutputPlayButton.Text = 'Play';
            app.OutputPauseButton = uibutton(app.UIFigure, 'push');
            app.OutputPauseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputPauseButtonPushed, true);
            app.OutputPauseButton.Text = 'Stop';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'DSCF2697.jpg';
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Visible = 'off';
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.Visible = 'off';
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Visible = 'off';
            app.PhaseEditFieldLabel.Text = 'Phase ';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditField.Visible = 'off';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Visible = 'off';
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.Visible = 'off';
            app.SampleFreq1000EditFieldLabel = uilabel(app.UIFigure);
            app.SampleFreq1000EditFieldLabel.Visible = 'off';
            app.SampleFreq1000EditFieldLabel.Text = 'Sample Freq. (>1000)';
            app.SampleFreq1000EditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleFreq1000EditField.Visible = 'off';
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Visible = 'off';
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Visible = 'off';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Visible = 'off';
            app.GenerateButton.Text = 'Generate!';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = 'DSCF2697.jpg';
            app.AttackEditFieldLabel = uilabel(app.UIFigure);
            app.AttackEditFieldLabel.Text = 'Attack';
            app.AttackEditField = uieditfield(app.UIFigure, 'numeric');
            app.SustainEditFieldLabel = uilabel(app.UIFigure);
            app.SustainEditFieldLabel.Text = 'Sustain';
            app.SustainEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidgetDropDownLabel = uilabel(app.UIFigure);
            app.WidgetDropDownLabel.Text = 'Widget';
            app.WidgetDropDown = uidropdown(app.UIFigure);
            app.WidgetDropDown.Items = {' ', 'ADSR Envelope', 'Option 3', 'Option 4'};
            app.WidgetDropDown.Value = ' ';
            app.Button = uibutton(app.UIFigure, 'push');
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.AttackLenEditFieldLabel = uilabel(app.UIFigure);
            app.AttackLenEditFieldLabel.Text = 'Attack Len.';
            app.AttackLenEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayLenEditFieldLabel = uilabel(app.UIFigure);
            app.DecayLenEditFieldLabel.Text = 'Decay Len.';
            app.DecayLenEditField = uieditfield(app.UIFigure, 'numeric');
            app.SustainLenEditFieldLabel = uilabel(app.UIFigure);
            app.SustainLenEditFieldLabel.Text = 'Sustain Len.';
            app.SustainLenEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecLenEditFieldLabel = uilabel(app.UIFigure);
            app.RecLenEditFieldLabel.Text = 'Rec. Len.';
            app.RecLenEditField = uieditfield(app.UIFigure, 'numeric');
            app.DemoSongsDropDownLabel = uilabel(app.UIFigure);
            app.DemoSongsDropDownLabel.Enable = 'off';
            app.DemoSongsDropDownLabel.Visible = 'off';
            app.DemoSongsDropDownLabel.Text = 'Demo Songs';
            app.DemoSongsDropDown = uidropdown(app.UIFigure);
            app.DemoSongsDropDown.Items = {'Happy Birthday', 'London Bridge', 'Anime', 'Option 4'};
            app.DemoSongsDropDown.ValueChangedFcn = createCallbackFcn(app, @DemoSongsDropDownValueChanged, true);
            app.DemoSongsDropDown.Enable = 'off';
            app.DemoSongsDropDown.Visible = 'off';
            app.DemoSongsDropDown.Value = 'Happy Birthday';
            app.thenclickaddEditFieldLabel = uilabel(app.UIFigure);
            app.thenclickaddEditFieldLabel.Enable = 'off';
            app.thenclickaddEditFieldLabel.Visible = 'off';
            app.thenclickaddEditFieldLabel.Text = {'1-8, then'; 'click add'};
            app.thenclickaddEditField = uieditfield(app.UIFigure, 'numeric');
            app.thenclickaddEditField.Enable = 'off';
            app.thenclickaddEditField.Visible = 'off';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Visible = 'off';
            app.AddButton.Text = 'Add';
            app.ChiaroClearButton = uibutton(app.UIFigure, 'push');
            app.ChiaroClearButton.ButtonPushedFcn = createCallbackFcn(app, @ChiaroClearButtonPushed, true);
            app.ChiaroClearButton.Enable = 'off';
            app.ChiaroClearButton.Visible = 'off';
            app.ChiaroClearButton.Text = 'Chiaro (Clear)';
            app.TypeforManualSongButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeforManualSongButtonGroup.Title = 'Type for Manual/Song';
            app.TypeforManualSongButtonGroup.Visible = 'off';
            app.NormalButton = uiradiobutton(app.TypeforManualSongButtonGroup);
            app.NormalButton.Text = 'Normal';
            app.NormalButton.Value = true;
            app.ADSRButton = uiradiobutton(app.TypeforManualSongButtonGroup);
            app.ADSRButton.Text = 'ADSR';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT of Input')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Graph of Input')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'FFT of Output')
            xlabel(app.UIAxes3, 'Frequency')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Graph of Output')
            xlabel(app.UIAxes4, 'Time')
            ylabel(app.UIAxes4, 'Amplitude')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end